classdef TTL2Table_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        OutputTablePanel       matlab.ui.container.Panel
        OutputTable            matlab.ui.control.Table
        DataKeyPanel           matlab.ui.container.Panel
        DataKeyTable           matlab.ui.control.Table
        CombineSignalsPanel    matlab.ui.container.Panel
        ResetButton            matlab.ui.control.Button
        MergeButton            matlab.ui.control.Button
        MergedNameEditField    matlab.ui.control.EditField
        MergeSignalsListBox    matlab.ui.control.ListBox
        DataKeyPathLabel       matlab.ui.control.Label
        TTLPathLabel           matlab.ui.control.Label
        SelectDataKeyButton    matlab.ui.control.Button
        SelectTTLFolderButton  matlab.ui.control.Button
    end


    properties (Access = private)
        data_key % Table mapping variable names to numeric values in the TTL signal
        data_key_loaded = false % Keep track of whether data key has been loaded
        states_vect % Stores data from "states.npy"
        samples_vect % Stores data from "sample_numbers.npy"
        npy_loaded = false % Tracks whether states and samples have been loaded
    end

    methods (Access = private)

        % Update the signals being analyzed in the multiselect box for
        % combining signals
        function updateMergeBox(app)
            data_key_temp = app.DataKeyTable.DisplayData;
            var_names = data_key_temp.VariableName(data_key_temp.Analyze);
            app.MergeSignalsListBox.Items = var_names;
        end

        % Combine the data key and the TTL data to find the signal
        % onsets/offsets
        function updateOutputTable(app)
            data_key_temp = app.DataKeyTable.DisplayData;
            data_key_temp = data_key_temp(data_key_temp.Analyze,1:end-1);
            varNames = data_key_temp.VariableName;
            [startIndCell,endIndCell] = deal(cell(length(varNames),1));
            % Copy data key table and append onsets and offsets to it
            for i = 1:length(varNames)
                startInds = app.samples_vect(...
                    app.states_vect == data_key_temp{i,"Key"});
                if size(startInds,1) > 1
                    startInds = startInds';
                end
                startIndCell{i} = startInds;
                % Offset is always key*-1
                endInds = app.samples_vect(...
                    app.states_vect == -data_key_temp{i,"Key"});
                if size(endInds,1) > 1
                    endInds = endInds';
                end
                endIndCell{i} = endInds;
            end
            output_table = [data_key_temp,...
                cell2table([startIndCell,endIndCell],"VariableNames",{'StartInds','EndInds'})];
            output_table.Properties.RowNames = output_table.VariableName;
            output_table.VariableName = [];
            app.OutputTable.Data = output_table;
            app.OutputTable.ColumnName = output_table.Properties.VariableNames;
            app.OutputTable.RowName = output_table.Properties.RowNames;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataKeyButton
        function SelectDataKeyButtonPushed(app, event)
            % Navigate to the data key file and load it in with readtable
            % Populate the data key navigator of the App with the data
            [file,path] = uigetfile("*.txt;*.csv;*.xls;*.xlsx","Select data key file:");
            if ~isequal(file,0)
                filepath = fullfile(path,file);
                app.DataKeyPathLabel.Text = filepath;
                app.data_key = readtable(filepath,...
                    'ReadVariableNames',true,...
                    'TextType','string');
                % Append logical values, which will be made editable
                app.data_key.Analyze = true(height(app.data_key),1);
                app.DataKeyTable.Data = app.data_key;
                app.DataKeyTable.ColumnName = app.data_key.Properties.VariableNames;
                isEditable = false(1,width(app.DataKeyTable.Data));
                isEditable(end) = true;
                app.DataKeyTable.ColumnEditable = isEditable;
                app.data_key_loaded = true;
                if app.npy_loaded
                    updateMergeBox(app)
                    updateOutputTable(app)
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            updateMergeBox(app)
        end

        % Cell edit callback: DataKeyTable
        function DataKeyTableCellEdit(app, event)
            updateMergeBox(app)
        end

        % Button pushed function: SelectTTLFolderButton
        function SelectTTLFolderButtonPushed(app, event)
            path = uigetdir("Select folder containing TTL .npy files:");
            if ~isequal(path,0)
                app.TTLPathLabel.Text = path;
                app.samples_vect = readNPY(fullfile(path,"sample_numbers.npy"));
                app.states_vect = readNPY(fullfile(path,"states.npy"));
                app.npy_loaded = true;
                if app.data_key_loaded
                    updateOutputTable(app)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectTTLFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectTTLFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTTLFolderButtonPushed, true);
            app.SelectTTLFolderButton.Text = 'Select TTL Folder';
            app.SelectDataKeyButton = uibutton(app.UIFigure, 'push');
            app.SelectDataKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataKeyButtonPushed, true);
            app.SelectDataKeyButton.Text = 'Select Data Key';
            app.TTLPathLabel = uilabel(app.UIFigure);
            app.TTLPathLabel.Text = 'TTL Path';
            app.DataKeyPathLabel = uilabel(app.UIFigure);
            app.DataKeyPathLabel.Text = 'Data Key Path';
            app.CombineSignalsPanel = uipanel(app.UIFigure);
            app.CombineSignalsPanel.Title = 'Combine Signals';
            app.MergeSignalsListBox = uilistbox(app.CombineSignalsPanel);
            app.MergeSignalsListBox.Multiselect = 'on';
            app.MergeSignalsListBox.Value = {'Item 1'};
            app.MergedNameEditField = uieditfield(app.CombineSignalsPanel, 'text');
            app.MergedNameEditField.Placeholder = 'New_Name';
            app.MergeButton = uibutton(app.CombineSignalsPanel, 'push');
            app.MergeButton.Text = 'Merge';
            app.ResetButton = uibutton(app.CombineSignalsPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.DataKeyPanel = uipanel(app.UIFigure);
            app.DataKeyPanel.Title = 'Data Key';
            app.DataKeyTable = uitable(app.DataKeyPanel);
            app.DataKeyTable.ColumnName = {'VariableName'; 'Key'; 'Analyze'};
            app.DataKeyTable.RowName = {};
            app.DataKeyTable.CellEditCallback = createCallbackFcn(app, @DataKeyTableCellEdit, true);
            app.OutputTablePanel = uipanel(app.UIFigure);
            app.OutputTablePanel.Title = 'Output Table';
            app.OutputTable = uitable(app.OutputTablePanel);
            app.OutputTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputTable.RowName = {};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTL2Table_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end